classdef DataAnalyzer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        EEE4416Label         matlab.ui.control.Label
        Label                matlab.ui.control.Label
        TabGroup             matlab.ui.container.TabGroup
        Tab                  matlab.ui.container.Tab
        BoundsTable          matlab.ui.control.Table
        DataAnalyzerLabel    matlab.ui.control.Label
        ColumnDropDown       matlab.ui.control.DropDown
        ColumnDropDownLabel  matlab.ui.control.Label
        PlotButton           matlab.ui.control.Button
        LoadButton           matlab.ui.control.Button
    end

    
    properties (Access = private)
        Data table %Stores the loaded data
    end
    
    methods (Access = private)
       
% Helper function to plot columns
        function plotColumn(app, colData, colName, ax, lower, upper)
            % Handle cell arrays first
            if iscell(colData)
                colData = app.convertCellData(colData);
            end
            
            % First, determine the appropriate data type with smart detection
            dataType = app.detectDataTypeSmart(colData, colName);
            
            % Now create appropriate plot based on detected data type
            switch dataType
                case 'numeric'
                    % For numeric data: Use tiledlayout for multiple plots
                    cla(ax);
                    
                    % Create tiled layout for multiple plots
                    t = tiledlayout(ax.Parent, 2, 1);
                    t.Padding = 'compact';
                    t.TileSpacing = 'compact';
                    
                    % Histogram in first tile
                    ax1 = nexttile(t);
                    histogram(ax1, colData);
                    xlabel(ax1, colName);
                    ylabel(ax1, 'Frequency');
                    title(ax1, [colName ' Histogram']);
                    grid(ax1, 'on');
                    
                    % Add bounds if provided (only for numeric data)
                    hold(ax1, 'on');
                    if ~isnan(lower)
                        xline(ax1, lower, '--r', 'Lower');
                    end
                    if ~isnan(upper)
                        xline(ax1, upper, '--g', 'Upper');
                    end
                    if ~isnan(lower) && ~isnan(upper)
                        inRange = (colData >= lower & colData <= upper);
                        pctIn = mean(inRange) * 100;
                        pctOut = 100 - pctIn;
                        subtitle(ax1, sprintf('%.1f%% in [%g–%g], %.1f%% anomalies', ...
                            pctIn, lower, upper, pctOut));
                    end
                    hold(ax1, 'off');
                    
                    % Box plot in second tile
                    ax2 = nexttile(t);
                    boxplot(ax2, colData);
                    ylabel(ax2, 'Values');
                    title(ax2, [colName ' Box Plot']);
                    grid(ax2, 'on');
                    
                    % Delete the original empty axes
                    delete(ax);
                    
                case 'categorical'
                    % For categorical data: Use tiledlayout for multiple plots
                    cla(ax);
                    
                    % Ensure it's categorical
                    if ~iscategorical(colData)
                        colData = categorical(colData);
                    end
                    
                    % Get categories and counts
                    cats = categories(colData);
                    counts = countcats(colData);
                    
                    % Create tiled layout
                    t = tiledlayout(ax.Parent, 1, 2);
                    t.Padding = 'compact';
                    t.TileSpacing = 'compact';
                    
                    % Bar chart in first tile
                    ax1 = nexttile(t);
                    bar(ax1, counts);
                    set(ax1, 'XTickLabel', cats, 'XTick', 1:numel(cats));
                    xlabel(ax1, colName);
                    ylabel(ax1, 'Count');
                    title(ax1, [colName ' Bar Chart']);
                    grid(ax1, 'on');
                    
                    % Rotate labels if many categories
                    if numel(cats) > 5
                        ax1.XTickLabelRotation = 45;
                    end
                    
                    % Pie chart or message in second tile
                    ax2 = nexttile(t);
                    if numel(cats) <= 10
                        pie(ax2, counts, cats);
                        title(ax2, [colName ' Pie Chart']);
                    else
                        text(ax2, 0.5, 0.5, 'Too many categories for pie chart', ...
                            'HorizontalAlignment', 'center');
                        axis(ax2, 'off');
                        title(ax2, [colName ' - Too Many Categories']);
                    end
                    
                    % Delete the original empty axes
                    delete(ax);
                    
                case {'datetime', 'text'}
                    % For datetime or text data: Simple histogram
                    cla(ax);
                    if isdatetime(colData)
                        histogram(ax, colData);
                        title(ax, [colName ' Date Distribution']);
                    else
                        % For text data, convert to categorical first
                        catData = categorical(colData);
                        histogram(ax, catData);
                        title(ax, [colName ' Category Distribution']);
                    end
                    xlabel(ax, colName);
                    ylabel(ax, 'Frequency');
                    grid(ax, 'on');
                    ax.XTickLabelRotation = 45;
                    
                case 'logical'
                    % For logical data: Pie chart
                    cla(ax);
                    counts = [sum(colData), sum(~colData)];
                    pie(ax, counts, {'True', 'False'});
                    title(ax, [colName ' Distribution (True/False)']);
                    
                otherwise
                    % For other data types including cell arrays that couldn't be converted
                    cla(ax);
                    text(ax, 0.5, 0.5, 'Unsupported or mixed data type for visualization', ...
                        'HorizontalAlignment', 'center');
                    title(ax, [colName ' - Unsupported Data Type']);
                    axis(ax, 'off');
            end
        end

        
% % You can add this helper function to detect data types:
%         function dataType = detectDataType(colData)
%             if isnumeric(colData)
%                 dataType = 'numeric';
%             elseif isdatetime(colData)
%                 dataType = 'datetime';
%             elseif islogical(colData)
%                 dataType = 'logical';
%             elseif iscategorical(colData)
%                 dataType = 'categorical';
%             elseif isstring(colData) || ischar(colData)
%                 dataType = 'text';
%             else
%                 dataType = 'unknown';
%             end
%         end
        
      
         % Smart data type detection function
        function dataType = detectDataTypeSmart(app, colData, colName)
            % Handle cell arrays first
            if iscell(colData)
                % Convert cell array to appropriate type
                colData = app.convertCellData(colData);
            end
            
            % Basic type checking
            if isdatetime(colData)
                dataType = 'datetime';
            elseif islogical(colData)
                dataType = 'logical';
            elseif iscategorical(colData)
                dataType = 'categorical';
                
            % Handle numeric data that might actually be categorical
            elseif isnumeric(colData)
                % Check if numeric data is actually categorical (limited unique values)
                uniqueVals = unique(colData);
                numUnique = numel(uniqueVals);
                
                % Heuristics for detecting categorical numeric data:
                % 1. Very few unique values (e.g., 1/0, 1/2/3, etc.)
                % 2. Integer values only
                % 3. Small range of values
                
                if numUnique <= 10 && all(floor(colData) == colData) && ~any(isnan(colData))
                    % Likely categorical numeric data (like 1/0 binary, or small integer codes)
                    dataType = 'categorical';
                else
                    % True numeric data
                    dataType = 'numeric';
                end
                
            % Handle string/char data
            elseif isstring(colData) || ischar(colData)
                % Convert to categorical for analysis
                if ischar(colData)
                    tempData = categorical(cellstr(colData));
                else
                    tempData = categorical(colData);
                end
                
                uniqueVals = categories(tempData);
                numUnique = numel(uniqueVals);
                
                if numUnique <= 20  % Reasonable number of categories
                    dataType = 'categorical';
                else
                    dataType = 'text'; % Too many categories, treat as text
                end
                
            else
                dataType = 'unknown';
            end
            
            % Additional heuristic: Check column name for hints
            if contains(lower(colName), {'category', 'type', 'class', 'group', 'flag', 'status', 'code'})
                dataType = 'categorical';
            elseif contains(lower(colName), {'date', 'time'})
                dataType = 'datetime';
            end
        end

        % Helper function to convert cell array data to appropriate type
        function convertedData = convertCellData(app, cellData)
            % Try to detect the most common type in the cell array
            nonEmptyCells = cellData(~cellfun(@isempty, cellData));
            
            if isempty(nonEmptyCells)
                convertedData = [];
                return;
            end
            
            % Check if all elements are numeric
            isNumeric = all(cellfun(@isnumeric, nonEmptyCells));
            if isNumeric
                convertedData = cell2mat(cellData);
                return;
            end
            
            % Check if all elements are strings or chars
            isString = all(cellfun(@ischar, nonEmptyCells)) || all(cellfun(@isstring, nonEmptyCells));
            if isString
                % Convert to string array first, then categorical
                strData = string(cellData);
                convertedData = categorical(strData);
                return;
            end
            
            % Check if mixed types - try to convert to categorical
            try
                % Try to convert to string first, then categorical
                strData = string(cellData);
                convertedData = categorical(strData);
            catch
                % If all else fails, return as cell array (will be handled as unknown)
                convertedData = cellData;
            end
        end
        
        % Helper function to show bounds table for all columns

        function showBoundsTable(app, selectedValue)
            if strcmp(selectedValue, "All")
                % Show bounds table for ALL columns
                allCols = app.Data.Properties.VariableNames;
                
                n = numel(allCols);
                tblData = cell(n, 3);
                for i = 1:n
                    colName = allCols{i};
                    colData = app.Data.(colName);
                    
                    tblData{i, 1} = colName;
                    
                    % Only show bounds for numeric columns
                    if isnumeric(colData)
                        tblData{i, 2} = NaN; % Lower bound
                        tblData{i, 3} = NaN; % Upper bound
                    else
                        tblData{i, 2} = 'N/A'; % Not applicable for non-numeric
                        tblData{i, 3} = 'N/A'; % Not applicable for non-numeric
                    end
                end
                
            else
                % Show bounds table for ONLY the selected column
                colData = app.Data.(selectedValue);
                
                tblData = cell(1, 3);
                tblData{1, 1} = selectedValue;
                
                % Only show bounds for numeric columns
                if isnumeric(colData)
                    tblData{1, 2} = NaN; % Lower bound
                    tblData{1, 3} = NaN; % Upper bound
                else
                    tblData{1, 2} = 'N/A'; % Not applicable for non-numeric
                    tblData{1, 3} = 'N/A'; % Not applicable for non-numeric
                end
            end
            
            % Update the table
            app.BoundsTable.Data = tblData;
            app.BoundsTable.ColumnName = {'Column', 'Lower Bound', 'Upper Bound'};
            app.BoundsTable.ColumnEditable = [false true true];
            app.BoundsTable.Visible = 'on';
            
        end
    end
                            

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Initial setup - hide bounds controls initially
            app.BoundsTable.Visible = 'off';
            app.ColumnDropDown.Items = {'Select a file first'};

        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
              [file, path] = uigetfile({'*.csv;*.xls;*.xlsx', 'Data Files (*.csv, *.xls, *.xlsx)';
                                          '*.csv', 'CSV Files (*.csv)';
                                          '*.xls;*.xlsx', 'Excel Files (*.xls, *.xlsx)'}, ...
                                         'Select Data File');
                if isequal(file, 0)
                    return;
                end
                
                try
                    [~, ~, ext] = fileparts(file);
                    ext = lower(ext);
                    
                    % Load data
                    if strcmp(ext, '.csv')
                        app.Data = readtable(fullfile(path, file));
                    elseif strcmp(ext, '.xls') || strcmp(ext, '.xlsx')
                        app.Data = readtable(fullfile(path, file));
                    else
                        uialert(app.UIFigure, 'Unsupported file format. Please use CSV or Excel files.', 'File Error');
                        return;
                    end
                    
                    % Update dropdown
                    cols = app.Data.Properties.VariableNames;
                    app.ColumnDropDown.Items = [{'All'}, cols];
                    app.ColumnDropDown.Value = 'All'; % Set to All by default
                    
                    % Clear previous data
                    app.BoundsTable.Data = {};
                    
                    % Clear any existing plots
                    if ~isempty(app.TabGroup.Children)
                        delete(app.TabGroup.Children);
                    end
                    
         
                    app.showBoundsTable('All');
                    
                    uialert(app.UIFigure, sprintf('Successfully loaded %s with %d rows and %d columns', ...
                           file, size(app.Data, 1), size(app.Data, 2)), 'File Loaded');
                    
                catch ME
                    uialert(app.UIFigure, ['Error loading file: ' ME.message], 'Load Error');
                end
        end

        % Drop down opening function: ColumnDropDown
        function ColumnDropDownOpening(app, event)
            
        end

        % Value changed function: ColumnDropDown
        function ColumnDropDownValueChanged(app, event)
            value = app.ColumnDropDown.Value;
            app.showBoundsTable(value);
            
        
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)

            % Clear old tabs
            delete(app.TabGroup.Children);
        
            % Check if data exists
            if isempty(app.Data)
                uialert(app.UIFigure, 'Please load a data file first.', 'No Data');
                return;
            end
        
            value = app.ColumnDropDown.Value;
        
            % Check if bounds table has data
            if isempty(app.BoundsTable.Data) || size(app.BoundsTable.Data, 1) == 0
                uialert(app.UIFigure, 'Please set bounds in the table first.', 'No Bounds Set');
                return;
            end
        
            if strcmp(value, "All")
                % Plot all columns from the bounds table
                tbl = app.BoundsTable.Data;
                for i = 1:size(tbl, 1)
                    colName = tbl{i, 1};
                    lower = tbl{i, 2};
                    upper = tbl{i, 3};
                    
                    % Check if column exists
                    if ismember(colName, app.Data.Properties.VariableNames)
                        colData = app.Data.(colName);
                        
                        % Handle bounds
                        if isnumeric(colData)
                            if isempty(lower) || isnan(lower) || ischar(lower)
                                lower = NaN;
                            end
                            if isempty(upper) || isnan(upper) || ischar(upper)
                                upper = NaN;
                            end
                        else
                            lower = NaN;
                            upper = NaN;
                        end
                        
                        % Create a tab and plot
                        t = uitab(app.TabGroup, 'Title', colName);
                        ax = axes('Parent', t);
                        ax.Position = [0.1 0.1 0.8 0.8];
                        app.plotColumn(colData, colName, ax, lower, upper);
                    end
                end
                
            else
                % Plot only the selected column
                colName = value;
                lower = app.BoundsTable.Data{1, 2};
                upper = app.BoundsTable.Data{1, 3};
                
                if ~ismember(colName, app.Data.Properties.VariableNames)
                    uialert(app.UIFigure, ['Column "' colName '" not found in data.'], 'Column Error');
                    return;
                end
                
                colData = app.Data.(colName);
                
                % Handle bounds
                if isnumeric(colData)
                    if isempty(lower) || isnan(lower) || ischar(lower)
                        lower = NaN;
                    end
                    if isempty(upper) || isnan(upper) || ischar(upper)
                        upper = NaN;
                    end
                else
                    lower = NaN;
                    upper = NaN;
                end
                
                % Create a tab and plot
                t = uitab(app.TabGroup, 'Title', colName);
                ax = axes('Parent', t);
                ax.Position = [0.1 0.1 0.8 0.8];
                app.plotColumn(colData, colName, ax, lower, upper);
            end
       
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.ColumnDropDownLabel = uilabel(app.UIFigure);
            app.ColumnDropDownLabel.Text = 'Column';
            app.ColumnDropDown = uidropdown(app.UIFigure);
            app.ColumnDropDown.Items = {'All', 'Option 2', 'Option 3', 'Option 4'};
            app.ColumnDropDown.DropDownOpeningFcn = createCallbackFcn(app, @ColumnDropDownOpening, true);
            app.ColumnDropDown.ValueChangedFcn = createCallbackFcn(app, @ColumnDropDownValueChanged, true);
            app.ColumnDropDown.Value = 'All';
            app.DataAnalyzerLabel = uilabel(app.UIFigure);
            app.DataAnalyzerLabel.Text = 'Data Analyzer';
            app.BoundsTable = uitable(app.UIFigure);
            app.BoundsTable.ColumnName = {'Column '; 'Lower Bound'; 'Upper bound'};
            app.BoundsTable.RowName = {};
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '220021326';
            app.EEE4416Label = uilabel(app.UIFigure);
            app.EEE4416Label.Text = 'EEE 4416';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DataAnalyzer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end